classdef Hodograph_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TitlePageTab                  matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        HodographMethodforGravityWaveDetectionLabel  matlab.ui.control.Label
        DevelopedbyThomasColliganZachYapKatelynPowellLabel  matlab.ui.control.Label
        Plot_3DTab                    matlab.ui.container.Tab
        Plot_3DButton                 matlab.ui.control.Button
        plot_3D                       matlab.ui.control.UIAxes
        min_3DSliderLabel             matlab.ui.control.Label
        min_3DSlider                  matlab.ui.control.Slider
        max_3DSliderLabel             matlab.ui.control.Label
        max_3DSlider                  matlab.ui.control.Slider
        Data_FileEditFieldLabel       matlab.ui.control.Label
        Data_FileEditField            matlab.ui.control.EditField
        Max_altEditFieldLabel         matlab.ui.control.Label
        Max_altEditField              matlab.ui.control.NumericEditField
        Min_altEditFieldLabel         matlab.ui.control.Label
        Min_altEditField              matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        Hodograph_RunTab              matlab.ui.container.Tab
        StartButton                   matlab.ui.control.Button
        Next_FileButton               matlab.ui.control.Button
        Save_DataButton               matlab.ui.control.Button
        Data_DirectoryEditFieldLabel  matlab.ui.control.Label
        Data_DirectoryEditField       matlab.ui.control.EditField
        UIAxes5                       matlab.ui.control.UIAxes
        Current_FileEditField_2Label  matlab.ui.control.Label
        Current_FileEditField         matlab.ui.control.EditField
        WarningsEditFieldLabel        matlab.ui.control.Label
        WarningsEditField             matlab.ui.control.EditField
        Save_Data_DIrectoryEditFieldLabel  matlab.ui.control.Label
        Save_Data_DIrectoryEditField  matlab.ui.control.EditField
        Main_DirectoryEditFieldLabel  matlab.ui.control.Label
        Main_DirectoryEditField       matlab.ui.control.EditField
        Min_ALTSpinnerLabel           matlab.ui.control.Label
        Min_ALTSpinner                matlab.ui.control.Spinner
        Max_ALTSpinnerLabel           matlab.ui.control.Label
        Max_ALTSpinner                matlab.ui.control.Spinner
        Label_3                       matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Exampleellipsewithaltituderangeof18400to19300mLabel  matlab.ui.control.Label
        Plot_HodographTab             matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        PlotButton                    matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        temp;
        data;
        Alt;
        u;
        v;
        bvFreqSquared;
        upper;
        lower;
        i = 1;
        wl = 12000;
        wu = 33000;
        filesp
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Plot_3DButton
        function Plot_3DButtonPushed(app, event)
            datal = readDFM17(convertCharsToStrings(app.Data_FileEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            if max3d>max(datal.Alt)
                max3d = max(datal.Alt);
                app.max_3DSlider.Value = max(datal.Alt);
            end
            t_u = find(datal.Alt>=max3d,1,'first');
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
            title(app.plot_3D,'3D u and v plot')
            app.Max_altEditField.Value = max3d;
            app.Min_altEditField.Value = min3d;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            d = append(app.Main_DirectoryEditField.Value,app.Data_DirectoryEditField.Value);
            app.i =1;
            il = app.i;
            t = fullfile(d, "*.txt");
            files = dir(t);
            app.filesp = files;
            current = files(il).name;
            current = fullfile(d, current);
            app.data = readRadioSondeData(current);
            [~, idx] = max(app.data.Alt);
            app.data = app.data(1:idx, :);
            app.data = app.data(app.data.Alt > 12000, :);
            if isempty(app.data)
                app.WarningsEditField.Value = 'No data above 12000m';
            end
            RS = mean(app.data.Rs);
            app.u = -(app.data.Ws) .* sind(app.data.Wd); % from MetPy
            app.v = -(app.data.Ws) .* cosd(app.data.Wd); %    
            [app.Alt, app.u, app.v, app.temp, app.bvFreqSquared] = ... 
            preprocessDataNoResample(app.data.Alt, app.u, app.v, app.data.T, app.data.P, RS);
            app.Current_FileEditField.Value = files(il).name;
            wll = app.Min_ALTSpinner.Value;
            wul = app.Max_ALTSpinner.Value;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
            title(app.UIAxes5,' ')
        end

        % Button pushed function: Next_FileButton
        function Next_FileButtonPushed(app, event)
            d = append(app.Main_DirectoryEditField.Value,app.Data_DirectoryEditField.Value);
            
            t = fullfile(d, "*.txt");
            files = dir(t);
            app.i = app.i+1;
            il = app.i;
            if app.i > size(files)
                app.WarningsEditField.Value = 'END of flight data';
            end
            current = files(il).name;
            current = fullfile(d, current);
            app.data = readRadioSondeData(current);
            [~, idx] = max(app.data.Alt);
            app.data = app.data(1:idx, :);
            app.data = app.data(app.data.Alt > 12000, :);
            if isempty(app.data)
                app.WarningsEditField.Value = 'No data above 12000m';
                app.i = app.i+1;
            end
            RS = mean(app.data.Rs);
            app.u = -(app.data.Ws) .* sind(app.data.Wd); % from MetPy
            app.v = -(app.data.Ws) .* cosd(app.data.Wd); %    
            [app.Alt, app.u, app.v, app.temp, app.bvFreqSquared] = ... 
            preprocessDataNoResample(app.data.Alt, app.u, app.v, app.data.T, app.data.P, RS);
            app.Current_FileEditField.Value = files(il).name;
            wll = app.Min_ALTSpinner.Value;
            wul = app.Max_ALTSpinner.Value;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
            title(app.UIAxes5,' ')
        end

        % Button pushed function: Save_DataButton
        function Save_DataButtonPushed(app, event)
            fprintf("%d, %d\n", app.Alt(app.lower), app.Alt(app.upper));
            T = table(app.Alt(app.lower:app.upper), app.u(app.lower:app.upper), app.v(app.lower:app.upper), app.temp(app.lower:app.upper), app.bvFreqSquared(app.lower:app.upper));
            T.Properties.VariableNames = {'Alt' 'u' 'v' 'temp' 'bv2'};
            [~, n, ~] = fileparts(app.filesp(app.i).name);
            fname = sprintf("%s\\%03u_%s-%d-%d.txt",append(app.Main_DirectoryEditField.Value,app.Save_Data_DIrectoryEditField.Value),app.i, n, app.Alt(app.lower), app.Alt(app.upper));
            writetable(T, fname);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            d =  append(app.Main_DirectoryEditField.Value,app.Save_Data_DIrectoryEditField.Value);

            t = fullfile(d, "*.txt");
            files = dir(t); 
            axs = [];
            series_figure = figure;
            hodo_fig = figure; offset = 10;
            arrow_offset = 1 / 15;
            counter = 1;
            already_seen = [];
            flight_num = [];
            offsets = [];
            coriolisFreq = coriolisFrequency(30.25);
            unique_flights = strings(1,length(files));
            for i = 1:length(files)
                a = sprintf('%02u',i);
                h = append('F',a);
                unique_flights(i) = h;
            end
            for k=1:size(unique_flights, 2)
                for i=1:size(files)
                    flight_number = files(i).name(1:3);
                    flight_number(1) = 'F';
                if strcmp(flight_number, unique_flights(k)) == 0
                    %fprintf("%s %s\n", unique_flights(k), flight_number);
                    continue;
                end
                % if str2num(flight_number(2:end)) ~= 23
                %     continue;
                % end
                data = readtable(fullfile(d, files(i).name));
                set(0, 'CurrentFigure', hodo_fig);
                ax = subplot(6, 5, i);
                axis (ax,'equal'); % this is needed to keep plots circular
                axs = [axs;ax];
                eps = fit_ellipse(data.u, data.v, ax);
                hold on
                plot(data.u, data.v, 'k.')
                lambda_z = 2*(data.Alt(end) - data.Alt(1));
                m = 2*pi / lambda_z;
                p = eps.phi;
                rot = [cos(p) -sin(p); sin(p) cos(p)];
                uv = [data.u data.v];
                uvrot = rot*uv';
                urot = uvrot(1, :);
                dT = data.temp(2:end) - data.temp(1:end-1);
                dz = data.Alt(2:end) - data.Alt(1:end-1);
                wf = eps.long_axis / eps.short_axis;
                bvMean = mean(data.bv2);
                intrinsicFreq = coriolisFreq*wf;
                %fprintf("%0.10f, %0.10f ", (coriolisFreq^2 * m^2)/abs(bvMean), wf^2 - 1);
                k_h = sqrt((coriolisFreq^2*m^2)/(abs(bvMean))*(wf^2 - 1)); % horizontal wavenumber (1 / meters)
                %fprintf("%f %f\n", intrinsicFreq, wf);
                intrinsicHorizPhaseSpeed = intrinsicFreq / k_h; % m/s
                fprintf("m:%f, lz:%f, h:%f,, bv:%f\n", m, lambda_z, intrinsicHorizPhaseSpeed, bvMean);
                k_h_2 = sqrt((intrinsicFreq^2 - coriolisFreq^2 )* (m^2 / abs(bvMean)));
                int2 = intrinsicFreq/k_h_2;
                % fprintf("m:%f, lz:%f, h:%f, kh:%f\n", m, lambda_z, intrinsicHorizPhaseSpeed, int2);
                dTdz = dT./dz;
                eta = mean(dTdz.*urot(1:end-1));
                if eta < 0
                    p = p - pi;
                end
                str = sprintf("f: %s, ^{w}/{f}=%0.1f, t=%0.1f, m_z=%0.1f", ...
                    files(i).name(1:3), wf, azimuthFromUnitCircle(rad2deg(p)),...
                    lambda_z/1000);
                %title(str);
                fprintf("%s\n", str);
                set(0, 'CurrentFigure', series_figure);
                xlim([5, 155]);
                ylim([12, 33]);
                Alt_of_detection_km = mean(data.Alt) / 1000;
                x1 = counter*offset;
                x2 = counter*offset + wf*cosd(rad2deg(p));
                y1 = Alt_of_detection_km;
                y2 = Alt_of_detection_km + wf*sind(rad2deg(p));
                hold on;
                p1 = [x1 y1];
                p2 = [x2 y2];
                dp = p2-p1;
                %quiver(p1(1),p1(2),dp(1),dp(2), 'AutoScale', 'on', 'color','#9a0200', 'linewidth', 2);
                [xf, yf] = ds2nfu([x1 x2], [y1 y2]);
                annotation(gcf, 'arrow', xf,yf, 'color', '#9a0200', 'LineWidth', 2) %plot arrow angle of propagation length corresponds to magnitude of intrinsic frequency
                if strcmp(flight_number, "F23")
                    if Alt_of_detection_km > 20 && Alt_of_detection_km < 24
                        xc = [xf(1) - 0.125, xf(2)-0.02];
                        yc = [yf(1) + 0.1, yf(1)+0.05];
                        annotation(gcf, 'textarrow', xc, yc, 'String', 'Eclipse Wave #2')
                    elseif Alt_of_detection_km > 20
                        xc = [xf(1) - 0.125, xf(2)- 0.02];
                        yc = [yf(1) + 0.1, yf(1)+0.05];
                        annotation(gcf, 'textarrow', xc, yc, 'String', 'Eclipse Wave #3')
                    end
                    fprintf("%f\n", Alt_of_detection_km);
                end
                %plot([x1 x2], [y1 y2], 'color', '#9a0200', 'linewidth', 3);
                placeholder = [counter*offset counter*offset];
                plot(placeholder, [12, 32.5], 'k'); % plot Altitude in km.
                end
                offsets = [offsets, counter*offset];
                counter = counter + 1;
            end
            % 10 -> 150
            
            linkaxes(axs, 'xy');
            set(0, 'CurrentFigure', hodo_fig);
            sgtitle("All hodographs for the radiosonde campaign")
            set(0, 'CurrentFigure', series_figure);
            % %offsets = offsets(1:end-1);
            flight_num = flight_num(1:end-1, :);
            xticks(offsets);
            xticklabels(unique_flights); 
            set(gca,'XTickLabelRotation', 45, 'fontsize', 16)
            ylabel("Altitude of detection (km)") 
            xlabel("Flight number")
            title("Propagation direction and detection Altitude of gravity waves (hodograph method)", 'fontsize', 16);

        end

        % Value changed function: Min_ALTSpinner
        function Min_ALTSpinnerValueChanged(app, event)
            value = app.Min_ALTSpinner.Value;
            app.wl = value;
            wll = app.wl;
            wul = app.Max_ALTSpinner.Value;
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
        end

        % Value changed function: Max_ALTSpinner
        function Max_ALTSpinnerValueChanged(app, event)
            value = app.Max_ALTSpinner.Value;
            app.wu = value;
            wll = app.Min_ALTSpinner.Value;
            wul = app.wu;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
        end

        % Value changed function: min_3DSlider
        function min_3DSliderValueChanged(app, event)
            value = app.min_3DSlider.Value;
            datal = readDFM17(convertCharsToStrings(app.Data_FileEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            if max3d>max(datal.Alt)
                max3d = max(datal.Alt);
                app.max_3DSlider.Value = max(datal.Alt);
            end
            t_u = find(datal.Alt>=max3d,1,'first');
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
            app.Min_altEditField.Value = value;
        end

        % Value changed function: max_3DSlider
        function max_3DSliderValueChanged(app, event)
            value = app.max_3DSlider.Value;
            datal = readDFM17(convertCharsToStrings(app.Data_FileEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            if max3d>max(datal.Alt)
                max3d = max(datal.Alt);
                app.max_3DSlider.Value = max(datal.Alt);
            end
            t_u = find(datal.Alt>=max3d,1,'first');
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
            app.Max_altEditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TitlePageTab = uitab(app.TabGroup);
            app.TitlePageTab.Title = 'Title Page';
            app.Image = uiimage(app.TitlePageTab);
            app.Image.ImageSource = 'Labcoats_Logo.png';
            app.HodographMethodforGravityWaveDetectionLabel = uilabel(app.TitlePageTab);
            app.HodographMethodforGravityWaveDetectionLabel.Text = 'Hodograph Method for Gravity Wave Detection';
            app.DevelopedbyThomasColliganZachYapKatelynPowellLabel = uilabel(app.TitlePageTab);
            app.DevelopedbyThomasColliganZachYapKatelynPowellLabel.Text = 'Developed by: Thomas Colligan, Zach Yap, Katelyn Powell';
            app.Plot_3DTab = uitab(app.TabGroup);
            app.Plot_3DTab.Title = 'Plot_3D';
            app.Plot_3DButton = uibutton(app.Plot_3DTab, 'push');
            app.Plot_3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_3DButtonPushed, true);
            app.Plot_3DButton.Text = 'Plot_3D';
            app.plot_3D = uiaxes(app.Plot_3DTab);
            title(app.plot_3D, 'Title')
            xlabel(app.plot_3D, 'X')
            ylabel(app.plot_3D, 'Y')
            app.min_3DSliderLabel = uilabel(app.Plot_3DTab);
            app.min_3DSliderLabel.Text = 'min_3D';
            app.min_3DSlider = uislider(app.Plot_3DTab);
            app.min_3DSlider.Limits = [10000 33000];
            app.min_3DSlider.ValueChangedFcn = createCallbackFcn(app, @min_3DSliderValueChanged, true);
            app.min_3DSlider.Value = 10000;
            app.max_3DSliderLabel = uilabel(app.Plot_3DTab);
            app.max_3DSliderLabel.Text = 'max_3D';
            app.max_3DSlider = uislider(app.Plot_3DTab);
            app.max_3DSlider.Limits = [10000 33000];
            app.max_3DSlider.ValueChangedFcn = createCallbackFcn(app, @max_3DSliderValueChanged, true);
            app.max_3DSlider.Value = 33000;
            app.Data_FileEditFieldLabel = uilabel(app.Plot_3DTab);
            app.Data_FileEditFieldLabel.Text = 'Data_File';
            app.Data_FileEditField = uieditfield(app.Plot_3DTab, 'text');
            app.Data_FileEditField.Tooltip = {'Format Example: C:\Users\user\Desktop\eclipseData\W8_L1_1100UTC_072220_ZASUS_Profile.txt'};
            app.Max_altEditFieldLabel = uilabel(app.Plot_3DTab);
            app.Max_altEditFieldLabel.Text = 'Max_alt';
            app.Max_altEditField = uieditfield(app.Plot_3DTab, 'numeric');
            app.Max_altEditField.ValueDisplayFormat = '%.0f';
            app.Min_altEditFieldLabel = uilabel(app.Plot_3DTab);
            app.Min_altEditFieldLabel.Text = 'Min_alt';
            app.Min_altEditField = uieditfield(app.Plot_3DTab, 'numeric');
            app.Min_altEditField.ValueDisplayFormat = '%.0f';
            app.Label_2 = uilabel(app.Plot_3DTab);
            app.Label_2.Text = {'Copy and paste data file location to use for gravity wave detection.'; 'Select "Plot_3D".'; 'Adjust the sliders to determine ellipse altitudes. The 3D plot can be rotated using options given in upper right corner of the image.'};
            app.Hodograph_RunTab = uitab(app.TabGroup);
            app.Hodograph_RunTab.Title = 'Hodograph_Run';
            app.StartButton = uibutton(app.Hodograph_RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Next_FileButton = uibutton(app.Hodograph_RunTab, 'push');
            app.Next_FileButton.ButtonPushedFcn = createCallbackFcn(app, @Next_FileButtonPushed, true);
            app.Next_FileButton.Text = 'Next_File';
            app.Save_DataButton = uibutton(app.Hodograph_RunTab, 'push');
            app.Save_DataButton.ButtonPushedFcn = createCallbackFcn(app, @Save_DataButtonPushed, true);
            app.Save_DataButton.Text = 'Save_Data';
            app.Data_DirectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Data_DirectoryEditFieldLabel.Text = 'Data_Directory';
            app.Data_DirectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Data_DirectoryEditField.Tooltip = {'Data_Directory is the folder the data is in, formatted ".\folder_name"'};
            app.Data_DirectoryEditField.Value = '.\eclipseData';
            app.UIAxes5 = uiaxes(app.Hodograph_RunTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.Current_FileEditField_2Label = uilabel(app.Hodograph_RunTab);
            app.Current_FileEditField_2Label.Text = 'Current_File';
            app.Current_FileEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.WarningsEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.WarningsEditFieldLabel.Text = 'Warnings';
            app.WarningsEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Save_Data_DIrectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Save_Data_DIrectoryEditFieldLabel.Text = 'Save_Data_DIrectory';
            app.Save_Data_DIrectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Save_Data_DIrectoryEditField.Tooltip = {'Save_Data_Directory is the folder the ellipse data files will be saved, formatted ".\folder_name"'};
            app.Save_Data_DIrectoryEditField.Value = '.\hodograph';
            app.Main_DirectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Main_DirectoryEditFieldLabel.Text = 'Main_Directory';
            app.Main_DirectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Main_DirectoryEditField.Tooltip = {'Main_Directory is the file location without the folder data is in'};
            app.Min_ALTSpinnerLabel = uilabel(app.Hodograph_RunTab);
            app.Min_ALTSpinnerLabel.Text = 'Min_ALT';
            app.Min_ALTSpinner = uispinner(app.Hodograph_RunTab);
            app.Min_ALTSpinner.Step = 100;
            app.Min_ALTSpinner.Limits = [12000 33000];
            app.Min_ALTSpinner.ValueDisplayFormat = '%.0f';
            app.Min_ALTSpinner.ValueChangedFcn = createCallbackFcn(app, @Min_ALTSpinnerValueChanged, true);
            app.Min_ALTSpinner.Value = 12000;
            app.Max_ALTSpinnerLabel = uilabel(app.Hodograph_RunTab);
            app.Max_ALTSpinnerLabel.Text = 'Max_ALT';
            app.Max_ALTSpinner = uispinner(app.Hodograph_RunTab);
            app.Max_ALTSpinner.Step = 100;
            app.Max_ALTSpinner.Limits = [12000 33000];
            app.Max_ALTSpinner.ValueDisplayFormat = '%.0f';
            app.Max_ALTSpinner.ValueChangedFcn = createCallbackFcn(app, @Max_ALTSpinnerValueChanged, true);
            app.Max_ALTSpinner.Value = 33000;
            app.Label_3 = uilabel(app.Hodograph_RunTab);
            app.Label_3.Text = {'1. Select "Start".'; '2. Adjust Spinners to the altitude that detected gravity'; 'wave ellipses were found in the "Plot_3D" tab.'; '3. Continue in the same file adjusting altitudes'; 'to the various ellipses in the data file, saving the'; 'ellipses as you go.'; '4. Once all ellipses are recorded, select'; '"Next File" and continue this process until'; 'complete. **You will need to change the'; 'file name in the "Plot_3D" tab, Data_File line.'};
            app.Image3 = uiimage(app.Hodograph_RunTab);
            app.Image3.ImageSource = 'untitled1.png';
            app.Exampleellipsewithaltituderangeof18400to19300mLabel = uilabel(app.Hodograph_RunTab);
            app.Exampleellipsewithaltituderangeof18400to19300mLabel.Text = 'Example ellipse with altitude range of 18400 to 19300 m';
            app.Plot_HodographTab = uitab(app.TabGroup);
            app.Plot_HodographTab.Title = 'Plot_Hodograph';
            app.GridLayout = uigridlayout(app.Plot_HodographTab);
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = {'Select "Plot".'; 'A new window should open with all the files plotted that are located in the save folder.'; '**All data location spaces need to be filled on the "Hodograph_Run" tab, accurately, to create plots'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hodograph_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end